classdef systemConfigurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SystemConfiguratorUIFigure     matlab.ui.Figure
        logTable                       matlab.ui.control.ListBox
        PlotSelectedSignalinFigureButton  matlab.ui.control.Button
        LoggeddataLabel                matlab.ui.control.Label
        PlotSelectedSignalinSDIButton  matlab.ui.control.Button
        PlotAllinSDIButton             matlab.ui.control.Button
        playButton                     matlab.ui.control.Button
        SwapButton                     matlab.ui.control.Button
        swapValuesTable                matlab.ui.control.Table
        ViewButton                     matlab.ui.control.Button
        EditSelectedPartLabel          matlab.ui.control.Label
        propTable                      matlab.ui.control.Table
        swapList                       matlab.ui.control.ListBox
        SwapforanotherPartinthefamilyListBoxLabel  matlab.ui.control.Label
        EditSelectedPartdatafileLabel  matlab.ui.control.Label
        openPart                       matlab.ui.control.Hyperlink
        modelname                      matlab.ui.control.EditField
        ModelLabel                     matlab.ui.control.Label
        objTree                        matlab.ui.container.Tree
    end


    properties (Access = public)
        obj; % Description
    end

    properties (Access = private)
        objname % Name of the top object in the model
        SelectedNode % Description
        fileCache % Description
    end

    methods (Access = private)

        function node = updateTree(app,obj,t)
            % Recursive function that build the tree of objects
            props = properties(obj);
            for i = 1:length(props)     
                node(i) = uitreenode(t,'Text',props{i},'NodeData',obj.(props{i}));
                if isa(obj.(props{i}),'slVASPart')
                    node(i).Text = [node(i).Text ' (' class(obj.(props{i})) ')'];
                    defaultObj = eval(class(obj)).(props{i});
                    updateBlockPath(app,defaultObj,obj.(props{i}))
                    if isequal(defaultObj, obj.(props{i}))
%                         node(i).Icon = 'greenObject.svg';
                         node(i).Icon = 'ok.png';

                    else
%                         node(i).Icon = 'redObject.svg';
                        node(i).Icon = 'edit.png';

                    end
                    app.updateTree(obj.(props{i}),node(i));
                elseif isempty(obj.(props{i}))
%                     node(i).Text = [node(i).Text ' (' class(obj.(props{i})) ')'];
                else
                    val = obj.(props{i});
                    [M,N] = size(val);
                    if (M>1)
                        val = obj.(props{i});
                        val = val(1,:);
                    end
                    if (N>3)
                        val = val(1,1:3);
                        node(i).Text = [node(i).Text ' = ' num2str(val) '  ...'];
                    else
                        node(i).Text = [node(i).Text ' = ' num2str(val)];
                    end

%                     node(i).Text = [node(i).Text ' = ' num2str(val)];
                    if eval(class(obj)).(props{i}) == obj.(props{i})
%                         node(i).Icon = 'greenObject.svg';
                         node(i).Icon = 'ok.png';

                    else
%                         node(i).Icon = 'redObject.svg';
                        node(i).Icon = 'edit.png';

                    end
                end
            end
        end

        function theTable = getPropTable(~,obj)
            % Create the table to edit properties of the selected object
            props = properties(obj);
            for i = 1:length(props)
                name{i,1} = props{i};
                if isa(obj.(props{i}),'slVASPart')
                    value{i,1} = class(obj.(props{i}));
                else
                    value{i,1} = num2str(obj.(props{i})); %#ok<*AGROW>
                end
                theTable = table(name,value);
            end
        end


        function updateSwapTable(app, obj)
            % Called to refresh the list of swappable parts
            if isa(obj,'slVASPart')
                superClass = superclasses(obj);
                md = metaclass(obj);
                md.SuperclassList.Name; % Assume only one superclass
%                 p = currentProject();
                parts = {};
                cnt = 0;
                % This whole loop takes way too much time, should be
                % indexed once
                for i = 1:length(app.fileCache)
                    theFilePath = app.fileCache{i};
                    theFilePathChar = char(theFilePath);


                    testObj = fileToObj(app,theFilePathChar);
                    if not(isempty(testObj))
                        if isa(testObj,superClass{1})
                            cnt = cnt+1;
                            parts{cnt,1} = class(testObj);
                        end
                    end
                end
                %                     [~,filename,extension] = fileparts(theFilePathChar);
%                     if strcmp(extension,'.m')
%                         try % eval and isa will fail if it's not a Part
%                             if exist(filename,'class')==8
%                                 if isa(eval(filename),superClass(1))
%                                     cnt = cnt+1;
%                                     parts{cnt,1} = filename;
%                                 end
%                             end
%                         catch
%                             % it's ok :-)
%                         end
%                     end
%                 end
%                 parts(1) = [];  % First one if the superclass, remove from pickable list.
                app.swapList.Items = parts;
                for i = 1:length(app.swapList.Items)
                    if strcmp(app.swapList.Items{i},class(obj))
                        app.swapList.Value = app.swapList.Items{i};
                        break
                    end
                end
            data = eval(app.swapList.Value);
            app.swapValuesTable.Data = getPropTable(app,data);

            end

        end

        function recreateObjTree(app)
            delete(app.objTree.Children);
            node = uitreenode(app.objTree, ...
                'Text', [ app.objname ' (' class(app.obj) ')'], ...
                'NodeData', app.obj);
            app.updateTree(app.obj,node);
            expand(app.objTree,'all');
            unselectPart(app)   % Cannot figure out quickly how to restore the tree selection, so clearing it. To fix later
        end

        function unselectPart(app)
            % Clearing the Part editing table and swapable parts list when
            % we lose the selection in the tree
            app.propTable.Data = table([],[]);
            app.swapList.Items = {'---'};
            app.swapList.Value = {'---'};
            app.openPart.Text = 'slVASPart';
        end

        function FindSwappedNode(app,node,obj)
            if node.NodeData == obj
                app.SelectedNode = node;
                return;
            end
            for i = 1:length(node.Children)
                if node.Children(i).NodeData == obj
                    app.SelectedNode = node.Children(i);
                    return;
                elseif isa(node.Children(i).NodeData,'slVASPart')
                    FindSwappedNode(app,node.Children(i),obj)
                end
            end
        end
        
        function updateBlockPath(app,objNew,objOld)
            objNew.BlockPath = objOld.BlockPath;
            props = properties(objNew);
            for i = 1:length(props)
                if isa(objNew.(props{i}),'slVASPart')
                    try %#ok<TRYNC> 
                        updateBlockPath(app,objNew.(props{i}),objOld.(props{i}))
                    end
                end
            end
        end
        
        function updateLogTable(app, data)
            if ~isempty(data.Log)
                if data.Log.numElements > 0
                    Names = cell([data.Log.numElements 1]);
                    for i = 1:data.Log.numElements
                        if ~isempty(data.Log{i}.Name)
                            Names{i,1} = data.Log{i}.Name;
                        else
                            BlockPath = data.Log{i}.BlockPath.convertToCell;
                            Names{i,1} = BlockPath{end};
                        end
                    end
                    app.logTable.Items = Names;
                    app.logTable.Value = app.logTable.Items(1);
                else
                    app.logTable.Items = "--- no logged data ---";
                end
            else
                app.logTable.Items = "--- no logged data ---";
            end
        end
        
        function data = getSelectedData(app)
            selectedNodes = app.objTree.SelectedNodes;
            if isa(selectedNodes.NodeData,'slVASPart')
                data =  selectedNodes.NodeData;
            else
                data = app.objTree.SelectedNodes.Parent.NodeData;
            end
        end
        
        function obj = fileToObj(~,str)
            obj = [];
            % Do the analysis only if the extension in .m
            [~,~,extension] = fileparts(str);
            if strcmp(extension,'.m')
                % Find package folders
                k = strfind(str,'+');
                % Remove everything before the first "+"
                if not(isempty(k))
                    str = str(k(1)+1:end);
                end
                % Replace "\" by "."
                str = strrep(str,filesep,'.');
                str = strrep(str,'+','');
                % Remove final extension
                str = str(1:end-2);
                % evaluate to get the object
                try %#ok<TRYNC> 
                    obj = eval(str);
                end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mdl)
            % Copyright 2022 The MathWorks, Inc.
            
            % For debugging - remove the "mdl" argument in App Code Option,
            % this allows launching the app from here and the App does nto
            % close autpmatically when debugging
            % mdl = 'testVehicle';
            
            
            % Trigger a model update to fill the block path for synced
            % navigation. Need to be addressed
            if not(bdIsLoaded(mdl))
                load_system(mdl)
            end

            app.modelname.Value = mdl;

            blk = find_system(mdl,'MaskType','slVASPart');
            app.objname = get_param(blk{1},'obj');
            app.obj = evalin("base",app.objname);
            if isempty(app.obj.BlockPath)
%                 set_param(mdl,'SimulationCommand','update');
                evalBlks(app.obj,mdl);
            end
            

            % Caching the list of files in the project, "p.Files(i).Path" takes
            % forever, so saving it to a MAT-file
            try
                data = load('projectFilesCache');
                app.fileCache = data.fileCache;
            catch
                p = currentProject();
                for i = 1:length(p.Files)
                    app.fileCache{i} = p.Files(i).Path;
                end
            end

%             app.modelname.Value = mdl;
%             blk = find_system(app.modelname.Value,'MaskType','slVASPart'); % assume there is only one top-level Part
%             mo = Simulink.Mask.get(blk{1});
%             app.objname = mo.Parameters(1).Value;
%             app.obj = evalin("base",app.objname);
            recreateObjTree(app);
        end

        % Selection changed function: objTree
        function objTreeSelectionChanged(app, event)
            selectedNodes = app.objTree.SelectedNodes;
            if isa(selectedNodes.NodeData,'slVASPart')
                data =  selectedNodes.NodeData;
            else
                data = app.objTree.SelectedNodes.Parent.NodeData;
            end
                app.openPart.Text = class(data);
                app.propTable.Data = getPropTable(app,data);
                updateSwapTable(app, data);
                updateLogTable(app, data);
                evalBlks(app.obj,app.modelname.Value);
                %                 hilite_system('none');
                open_system(data.BlockPath,'force');
        end

        % Callback function: openPart
        function openPartHyperlinkClicked(app, event)
            edit(class(eval(app.openPart.Text)));
        end

        % Cell edit callback: propTable
        function propTableCellEdit(app, event)
            % Get the row and column of the change property
            indices = event.Indices;
            % Get the new value
            newData = event.NewData;
            % Big assumption here that the correct class is selected and
            % only one item is selected in the tree
            locObj = app.objTree.SelectedNodes.NodeData;
            props = properties(locObj);
            if isempty(props)   % This means we have a leaf node, get props form the parent node instead
                locObj = app.objTree.SelectedNodes.Parent.NodeData;
                props = properties(locObj);
            end
            if isnumeric(locObj.(props{indices(1)}))
                % The property being changed is numeric, so it's a leaf
                % node
                locObj.(props{indices(1)}) = str2double(newData);
                recreateObjTree(app);
                FindSwappedNode(app,app.objTree.Children,locObj);
                app.objTree.SelectedNodes = app.SelectedNode;
                selectedNodes = app.objTree.SelectedNodes;
                if isa(selectedNodes.NodeData,'slVASPart')
                    app.openPart.Text = class(selectedNodes.NodeData);
                    app.propTable.Data = getPropTable(app,selectedNodes.NodeData);
                    updateSwapTable(app, selectedNodes.NodeData);
                    evalBlks(app.obj,app.modelname.Value);
                    open_system(selectedNodes.NodeData.BlockPath,'force')
                end
            else
                locObj.(props{indices(1)}) = eval(newData);
                recreateObjTree(app);
                FindSwappedNode(app,app.objTree.Children,locObj);
                app.objTree.SelectedNodes = app.SelectedNode;
                %             updateSwapTable(app, app.SelectedNode.NodeData);
                selectedNodes = app.objTree.SelectedNodes;
                if isa(selectedNodes.NodeData,'slVASPart')
                    app.openPart.Text = class(selectedNodes.NodeData);
                    app.propTable.Data = getPropTable(app,selectedNodes.NodeData);
                    updateSwapTable(app, selectedNodes.NodeData);
                    evalBlks(app.obj,app.modelname.Value);
                    open_system(selectedNodes.NodeData.BlockPath,'force')
                end
            end

        end

        % Value changed function: swapList
        function swapListValueChanged(app, event)
            data = eval(app.swapList.Value);
            app.swapValuesTable.Data = getPropTable(app,data);


        end

        % Callback function
        function modelnameValueChanged(app, event)
            app.modelname.Value;

        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            %             blk = find_system(app.modelname.Value,'MaskType','slVASPart'); % assume there is only one top-level Part
            %             mo = Simulink.Mask.get(blk{1})
            %             app.objname = mo.Parameters(1).Value
            %             app.obj = evalin("base",app.objname);
            %             recreateObjTree(app);
            open_system(app.modelname.Value);
        end

        % Button pushed function: SwapButton
        function SwapButtonPushed(app, event)
            k = strfind(app.objTree.SelectedNodes.Text,' ');
            prop = app.objTree.SelectedNodes.Text(1:k-1);
            % Assign an object of the newly selected class to the
            % appropriate parent property
            if isa(app.objTree.SelectedNodes.Parent,'matlab.ui.container.Tree')
                blkPath = app.objTree.SelectedNodes.NodeData.BlockPath;
                newObj = eval(app.swapList.Value);
                newObj.BlockPath = blkPath;
                app.obj = newObj;
                assignin("base",app.objname,app.obj)
            elseif isa(app.objTree.SelectedNodes.Parent.NodeData,'slVASPart')
                blkPath = app.objTree.SelectedNodes.Parent.NodeData.(prop).BlockPath;
                newObj = eval(app.swapList.Value);
                app.objTree.SelectedNodes.Parent.NodeData.(prop) = newObj ;
                newObj.BlockPath = blkPath;
            else
                disp('should not be here')
            end
            recreateObjTree(app);

            FindSwappedNode(app,app.objTree.Children,newObj);
            app.objTree.SelectedNodes = app.SelectedNode;
            %             updateSwapTable(app, app.SelectedNode.NodeData);
            selectedNodes = app.objTree.SelectedNodes;
            if isa(selectedNodes.NodeData,'slVASPart')
                app.openPart.Text = class(selectedNodes.NodeData);
                app.propTable.Data = getPropTable(app,selectedNodes.NodeData);
                updateSwapTable(app, selectedNodes.NodeData);
                evalBlks(app.obj,app.modelname.Value);
                open_system(selectedNodes.NodeData.BlockPath,'force')
            end
            % Toggle variant facing to refresh
            set_param(app.modelname.Value, 'VariantFading','off')
            set_param(app.modelname.Value, 'VariantFading','on')
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            out = sim(bdroot(app.obj.BlockPath));
            getLog(app.obj,out);
        end

        % Button pushed function: PlotAllinSDIButton
        function PlotAllinSDIButtonPushed(app, event)
            Simulink.sdi.clear
            data = getSelectedData(app);
            if ~isempty(data.Log)
                plot(data.Log);
                theRun = Simulink.sdi.Run.getLatest;
                theRun.Name = class(data);
            end
        end

        % Button pushed function: PlotSelectedSignalinSDIButton
        function PlotSelectedSignalinSDIButtonPushed(app, event)
            data = getSelectedData(app);
            if ~isempty(data.Log)
                    for i = 1:length(app.logTable.Items)
                        if isequal(app.logTable.Items{i}, app.logTable.Value)
                            plot(data.Log{i});
                            return;
                        end
                    end
            end
        end

        % Button pushed function: PlotSelectedSignalinFigureButton
        function PlotSelectedSignalinFigureButtonPushed(app, event)
            data = getSelectedData(app);
            if ~isempty(data.Log)
                for i = 1:length(app.logTable.Items)
                    if isequal(app.logTable.Items{i}, app.logTable.Value)
                        figure;
                        plot(data.Log{i}.Values);
                        return;
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SystemConfiguratorUIFigure = uifigure('Visible', 'off');
            app.SystemConfiguratorUIFigure.Name = 'System Configurator';
            app.objTree = uitree(app.SystemConfiguratorUIFigure);
            app.objTree.SelectionChangedFcn = createCallbackFcn(app, @objTreeSelectionChanged, true);
            app.ModelLabel = uilabel(app.SystemConfiguratorUIFigure);
            app.ModelLabel.Text = 'Model:';
            app.modelname = uieditfield(app.SystemConfiguratorUIFigure, 'text');
            app.modelname.Value = 'testVehicle';
            app.openPart = uihyperlink(app.SystemConfiguratorUIFigure);
            app.openPart.HyperlinkClickedFcn = createCallbackFcn(app, @openPartHyperlinkClicked, true);
            app.openPart.Text = 'slPart';
            app.EditSelectedPartdatafileLabel = uilabel(app.SystemConfiguratorUIFigure);
            app.EditSelectedPartdatafileLabel.Text = 'Edit Selected Part data file:';
            app.SwapforanotherPartinthefamilyListBoxLabel = uilabel(app.SystemConfiguratorUIFigure);
            app.SwapforanotherPartinthefamilyListBoxLabel.Text = 'Swap for another Part in the family';
            app.swapList = uilistbox(app.SystemConfiguratorUIFigure);
            app.swapList.Items = {'---'};
            app.swapList.ValueChangedFcn = createCallbackFcn(app, @swapListValueChanged, true);
            app.swapList.Value = '---';
            app.propTable = uitable(app.SystemConfiguratorUIFigure);
            app.propTable.ColumnName = {'Parameter'; 'Value'};
            app.propTable.RowName = {};
            app.propTable.ColumnEditable = [false true];
            app.propTable.CellEditCallback = createCallbackFcn(app, @propTableCellEdit, true);
            app.EditSelectedPartLabel = uilabel(app.SystemConfiguratorUIFigure);
            app.EditSelectedPartLabel.Text = 'Edit Selected Part';
            app.ViewButton = uibutton(app.SystemConfiguratorUIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.swapValuesTable = uitable(app.SystemConfiguratorUIFigure);
            app.swapValuesTable.ColumnName = {'Parameter'; 'Value'};
            app.swapValuesTable.RowName = {};
            app.swapValuesTable.Multiselect = 'off';
            app.SwapButton = uibutton(app.SystemConfiguratorUIFigure, 'push');
            app.SwapButton.ButtonPushedFcn = createCallbackFcn(app, @SwapButtonPushed, true);
            app.SwapButton.Text = 'Swap';
            app.playButton = uibutton(app.SystemConfiguratorUIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = fullfile(pathToMLAPP, 'play.png');
            app.playButton.Text = '';
            app.PlotAllinSDIButton = uibutton(app.SystemConfiguratorUIFigure, 'push');
            app.PlotAllinSDIButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllinSDIButtonPushed, true);
            app.PlotAllinSDIButton.Text = 'Plot All in SDI';
            app.PlotSelectedSignalinSDIButton = uibutton(app.SystemConfiguratorUIFigure, 'push');
            app.PlotSelectedSignalinSDIButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedSignalinSDIButtonPushed, true);
            app.PlotSelectedSignalinSDIButton.Text = 'Plot Selected Signal in SDI';
            app.LoggeddataLabel = uilabel(app.SystemConfiguratorUIFigure);
            app.LoggeddataLabel.Text = 'Logged data';
            app.PlotSelectedSignalinFigureButton = uibutton(app.SystemConfiguratorUIFigure, 'push');
            app.PlotSelectedSignalinFigureButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedSignalinFigureButtonPushed, true);
            app.PlotSelectedSignalinFigureButton.Text = 'Plot Selected Signal in Figure';
            app.logTable = uilistbox(app.SystemConfiguratorUIFigure);
            app.logTable.Items = {'--- No logged data ---'};
            app.logTable.Value = '--- No logged data ---';
            % Show the figure after all components are created
            app.SystemConfiguratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = systemConfigurator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SystemConfiguratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SystemConfiguratorUIFigure)
        end
    end
end